classdef do_an < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        Tab2                            matlab.ui.container.Tab
        Tab2SoLanLapEditField           matlab.ui.control.EditField
        SlnlpEditFieldLabel_2           matlab.ui.control.Label
        Tab2ChonPhuongPhapDropDown      matlab.ui.control.DropDown
        PhngphpLabel                    matlab.ui.control.Label
        Tab2TimNghiemButton             matlab.ui.control.Button
        Tab2KetQuaEditField             matlab.ui.control.EditField
        KtquLabel                       matlab.ui.control.Label
        Tab2NhapSaiSoEditField          matlab.ui.control.NumericEditField
        NhpsaisEditFieldLabel           matlab.ui.control.Label
        Tab2KhoangPhanLyEditField       matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        Tab2NhapPhuongTrinhEditField    matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel       matlab.ui.control.Label
        Tab2UIAxes                      matlab.ui.control.UIAxes
        Tab3                            matlab.ui.container.Tab
        Tab3PhuongPhapDropDown          matlab.ui.control.DropDown
        ChnphngphpLabel                 matlab.ui.control.Label
        Tab3VeDoThiButton               matlab.ui.control.Button
        Tab3SoDoanConEditField          matlab.ui.control.NumericEditField
        SonconEditFieldLabel            matlab.ui.control.Label
        Tab3GioiHanDauCuoiEditField     matlab.ui.control.EditField
        GiihnucuiEditFieldLabel         matlab.ui.control.Label
        Tab3NhapY0EditField             matlab.ui.control.NumericEditField
        Nhpy0EditFieldLabel             matlab.ui.control.Label
        Tab3PTViPhanEditField           matlab.ui.control.EditField
        NhpphngtrnhviphnEditFieldLabel  matlab.ui.control.Label
        Tab3UIAxes                      matlab.ui.control.UIAxes
        Tab4                            matlab.ui.container.Tab
        Tab4KetQuaTaylorEditField       matlab.ui.control.EditField
        PhngphpTaylorEditFieldLabel_2   matlab.ui.control.Label
        Tab4TDaThucNoiSuyTextArea       matlab.ui.control.TextArea
        athcnisuyLabel                  matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        Tab4HienGiaTriXTextArea         matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        Tab4KetQuaLagrangeEditField     matlab.ui.control.NumericEditField
        PhngphpLagrangeEditFieldLabel   matlab.ui.control.Label
        Tab4TinhKetQuaButton            matlab.ui.control.Button
        Tab4GiaTriXEditField            matlab.ui.control.NumericEditField
        GitrcntnhohmEditFieldLabel      matlab.ui.control.Label
        Tab4DuLieuYEditField            matlab.ui.control.EditField
        NhpdliuyEditFieldLabel          matlab.ui.control.Label
        Tab4DuLieuXEditField            matlab.ui.control.EditField
        NhpdliuxEditFieldLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Tab4TinhKetQuaButton
        function Tab4TinhKetQuaButtonPushed(app, event)
            % Lấy dữ liệu từ giao diện người dùng
            xa = convertStringToNumArray(app.Tab4DuLieuXEditField.Value);
            ya = convertStringToNumArray(app.Tab4DuLieuYEditField.Value);
            x0 = app.Tab4GiaTriXEditField.Value;
            app.Tab4HienGiaTriXTextArea.Value = ['Giá trị đạo hàm tại x = ' num2str(x0)];
            
            % Tính đa thức nội suy Lagrange
            f_Lagrange = LagrangeSymbolic(xa, ya);
            % Hiển thị đa thức nội suy
            app.Tab4TDaThucNoiSuyTextArea.Value = char(f_Lagrange);
            % Tính giá trị đạo hàm tại x0
            f_dh = diff(f_Lagrange);
            f_dh_handle = matlabFunction(f_dh);
            result_Lagrange = f_dh_handle(x0);
            app.Tab4KetQuaLagrangeEditField.Value = result_Lagrange;

            % Tính giá trị đạo hàm tại x0 bằng phương pháp Taylor theo
            % chiều trung tâm
            result_Taylor = daohamTaylor(xa, ya, x0);
            app.Tab4KetQuaTaylorEditField.Value = num2str(result_Taylor);
        end

        % Button pushed function: Tab3VeDoThiButton
        function Tab3VeDoThiButtonPushed(app, event)
            fxy = app.Tab3PTViPhanEditField.Value;
            fxy = str2func(['@(x, y)' fxy]);
            N = app.Tab3SoDoanConEditField.Value;
            y0 = app.Tab3NhapY0EditField.Value;
            gioihan = convertStringToNumArray(app.Tab3GioiHanDauCuoiEditField.Value);
            a = gioihan(1);
            b = gioihan(2);
            method = app.Tab3PhuongPhapDropDown.Value;
            if (strcmp(method, 'trungdiem'))
                [x, y] = hienantrungdiem(fxy, a, b, y0, N);
            else
                [x, y] = RK(fxy, a, b, y0, N);
            end

            cla(app.Tab3UIAxes);
            plot(app.Tab3UIAxes, x, y, 'LineWidth', 2);
            title(app.Tab3UIAxes, 'Đồ thị phương trình');ve mau khac di
            xlabel(app.Tab3UIAxes, 'x');
            ylabel(app.Tab3UIAxes, 'y');
        end

        % Button pushed function: Tab2TimNghiemButton
        function Tab2TimNghiemButtonPushed(app, event)
            f = app.Tab2NhapPhuongTrinhEditField.Value;
            f = str2func(['@(x)' vectorize(f)]);
            saiso = app.Tab2NhapSaiSoEditField.Value;
            khoangphanly = convertStringToNumArray(app.Tab2KhoangPhanLyEditField.Value);
            a = khoangphanly(1);
            b = khoangphanly(2);
            method = app.Tab2ChonPhuongPhapDropDown.Value;
            if (strcmp(method, 'chiadoi'))
                [nghiem, solanlap] = chiadoi(f, a, b, saiso);
            else
                [nghiem, solanlap] = daycung(f, a, b, saiso);
            end

            app.Tab2KetQuaEditField.Value = num2str(nghiem);
            app.Tab2SoLanLapEditField.Value = num2str(solanlap);

            % Vẽ đồ thị
            x_plt = linspace(a, b, 1000);
            y_plt = f(x_plt);
            cla((app.Tab2UIAxes));
            plot(app.Tab2UIAxes, x_plt, y_plt, 'LineWidth',2);
            % Chú thích cho đồ thị
            title(app.Tab2UIAxes, 'Hàm số f'); 
            xlabel(app.Tab2UIAxes, 'x'); 
            ylabel(app.Tab2UIAxes, 'y');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2UIAxes = uiaxes(app.Tab2);
            title(app.Tab2UIAxes, 'Đồ thị hàm số y = f(x)')
            xlabel(app.Tab2UIAxes, 'X')
            ylabel(app.Tab2UIAxes, 'Y')
            zlabel(app.Tab2UIAxes, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.Tab2);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.Tab2NhapPhuongTrinhEditField = uieditfield(app.Tab2, 'text');
            app.Tab2NhapPhuongTrinhEditField.Value = 'x^2 -4';
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.Tab2);
            app.NhpkhongphnlynghimEditFieldLabel.Text = 'Nhập khoảng phân ly nghiệm';
            app.Tab2KhoangPhanLyEditField = uieditfield(app.Tab2, 'text');
            app.Tab2KhoangPhanLyEditField.Value = '1.5, 2.5';
            app.NhpsaisEditFieldLabel = uilabel(app.Tab2);
            app.NhpsaisEditFieldLabel.Text = 'Nhập sai số';
            app.Tab2NhapSaiSoEditField = uieditfield(app.Tab2, 'numeric');
            app.Tab2NhapSaiSoEditField.Value = 0.001;
            app.KtquLabel = uilabel(app.Tab2);
            app.KtquLabel.Text = 'Kết quả nghiệm';
            app.Tab2KetQuaEditField = uieditfield(app.Tab2, 'text');
            app.Tab2TimNghiemButton = uibutton(app.Tab2, 'push');
            app.Tab2TimNghiemButton.ButtonPushedFcn = createCallbackFcn(app, @Tab2TimNghiemButtonPushed, true);
            app.Tab2TimNghiemButton.Text = 'Tính toán';
            app.PhngphpLabel = uilabel(app.Tab2);
            app.PhngphpLabel.Text = 'Phương pháp';
            app.Tab2ChonPhuongPhapDropDown = uidropdown(app.Tab2);
            app.Tab2ChonPhuongPhapDropDown.Items = {'Chia đôi', 'Dây cung'};
            app.Tab2ChonPhuongPhapDropDown.ItemsData = {'chiadoi', 'daycung'};
            app.Tab2ChonPhuongPhapDropDown.Value = 'chiadoi';
            app.SlnlpEditFieldLabel_2 = uilabel(app.Tab2);
            app.SlnlpEditFieldLabel_2.Text = 'Số lần lặp';
            app.Tab2SoLanLapEditField = uieditfield(app.Tab2, 'text');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Tab3UIAxes = uiaxes(app.Tab3);
            title(app.Tab3UIAxes, 'Đồ thị hàm y = f(x)')
            xlabel(app.Tab3UIAxes, 'X')
            ylabel(app.Tab3UIAxes, 'Y')
            zlabel(app.Tab3UIAxes, 'Z')
            app.NhpphngtrnhviphnEditFieldLabel = uilabel(app.Tab3);
            app.NhpphngtrnhviphnEditFieldLabel.Text = 'Nhập phương trình vi phân';
            app.Tab3PTViPhanEditField = uieditfield(app.Tab3, 'text');
            app.Tab3PTViPhanEditField.Value = '(1-x) * y';
            app.Nhpy0EditFieldLabel = uilabel(app.Tab3);
            app.Nhpy0EditFieldLabel.Text = 'Nhập y0';
            app.Tab3NhapY0EditField = uieditfield(app.Tab3, 'numeric');
            app.Tab3NhapY0EditField.Value = 1;
            app.GiihnucuiEditFieldLabel = uilabel(app.Tab3);
            app.GiihnucuiEditFieldLabel.Text = 'Giới hạn đầu cuối';
            app.Tab3GioiHanDauCuoiEditField = uieditfield(app.Tab3, 'text');
            app.Tab3GioiHanDauCuoiEditField.Value = '0, 5';
            app.SonconEditFieldLabel = uilabel(app.Tab3);
            app.SonconEditFieldLabel.Text = 'Số đoạn con';
            app.Tab3SoDoanConEditField = uieditfield(app.Tab3, 'numeric');
            app.Tab3SoDoanConEditField.Value = 25;
            app.Tab3VeDoThiButton = uibutton(app.Tab3, 'push');
            app.Tab3VeDoThiButton.ButtonPushedFcn = createCallbackFcn(app, @Tab3VeDoThiButtonPushed, true);
            app.Tab3VeDoThiButton.Text = 'Vẽ đồ thị';
            app.ChnphngphpLabel = uilabel(app.Tab3);
            app.ChnphngphpLabel.Text = 'Chọn phương pháp';
            app.Tab3PhuongPhapDropDown = uidropdown(app.Tab3);
            app.Tab3PhuongPhapDropDown.Items = {'Hiện ẩn trung điểm', 'Runge Kutta'};
            app.Tab3PhuongPhapDropDown.ItemsData = {'trungdiem', 'rk'};
            app.Tab3PhuongPhapDropDown.Value = 'trungdiem';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.NhpdliuxEditFieldLabel = uilabel(app.Tab4);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.Tab4DuLieuXEditField = uieditfield(app.Tab4, 'text');
            app.Tab4DuLieuXEditField.Value = '0.1 0.3 0.5 0.7 0.9';
            app.NhpdliuyEditFieldLabel = uilabel(app.Tab4);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.Tab4DuLieuYEditField = uieditfield(app.Tab4, 'text');
            app.Tab4DuLieuYEditField.Value = '0.1002 0.3047 0.5236 0.7754 1.1198';
            app.GitrcntnhohmEditFieldLabel = uilabel(app.Tab4);
            app.GitrcntnhohmEditFieldLabel.Text = 'Giá trị cần tính đạo hàm';
            app.Tab4GiaTriXEditField = uieditfield(app.Tab4, 'numeric');
            app.Tab4GiaTriXEditField.Value = 0.14;
            app.Tab4TinhKetQuaButton = uibutton(app.Tab4, 'push');
            app.Tab4TinhKetQuaButton.ButtonPushedFcn = createCallbackFcn(app, @Tab4TinhKetQuaButtonPushed, true);
            app.Tab4TinhKetQuaButton.Text = 'Tính Đạo hàm';
            app.PhngphpLagrangeEditFieldLabel = uilabel(app.Tab4);
            app.PhngphpLagrangeEditFieldLabel.Text = 'Phương pháp Lagrange';
            app.Tab4KetQuaLagrangeEditField = uieditfield(app.Tab4, 'numeric');
            app.TextAreaLabel = uilabel(app.Tab4);
            app.TextAreaLabel.Text = '';
            app.Tab4HienGiaTriXTextArea = uitextarea(app.Tab4);
            app.Tab4HienGiaTriXTextArea.Value = {'Giá trị đạo hàm tại x = '};
            app.TextArea_2 = uitextarea(app.Tab4);
            app.TextArea_2.Value = {'       Nhập dữ liệu'};
            app.athcnisuyLabel = uilabel(app.Tab4);
            app.athcnisuyLabel.Text = 'Đa thức nội suy';
            app.Tab4TDaThucNoiSuyTextArea = uitextarea(app.Tab4);
            app.PhngphpTaylorEditFieldLabel_2 = uilabel(app.Tab4);
            app.PhngphpTaylorEditFieldLabel_2.Text = 'Phương pháp Taylor';
            app.Tab4KetQuaTaylorEditField = uieditfield(app.Tab4, 'text');
            app.Tab4KetQuaTaylorEditField.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = do_an

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end